classdef BA900DialogueBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BanksListBoxLabel          matlab.ui.control.Label
        BanksListBox               matlab.ui.control.ListBox
        InstructionsTextAreaLabel  matlab.ui.control.Label
        InstructionsTextArea       matlab.ui.control.TextArea
        YearButtonGroup            matlab.ui.container.ButtonGroup
        Button                     matlab.ui.control.RadioButton
        Button_2                   matlab.ui.control.RadioButton
        Button_3                   matlab.ui.control.RadioButton
        RunButton                  matlab.ui.control.Button
        TitleText                  matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        MonthDropDownLabel         matlab.ui.control.Label
        MonthDropDown              matlab.ui.control.DropDown
        UIAxes                     matlab.ui.control.UIAxes
        DataDropDownLabel          matlab.ui.control.Label
        DataDropDown               matlab.ui.control.DropDown
    end

    properties (Access = public)
        SelectedBanks
        Year
        Data
        Months
    end
    
    methods (Access = private)
        
        function updatetable(app, newMonth)
            app.UITable.Data = struct2table(app.Data.(app.MonthDropDown.Items{strcmpi(app.MonthDropDown.Items, newMonth)}));     
        end
    
        function updateplot(app, newValue)
            %app.UIAxes.Title.String = app.Data.(app.MonthDropDown.Value).(app.UITable.ColumnName{strcmpi(app.UITable.ColumnName, newValue)});
            categories = categorical({app.Data.(app.MonthDropDown.Items{1}).Name});
            values = [app.Data.(app.MonthDropDown.Value).(app.DataDropDown.Items{strcmpi(app.DataDropDown.Items, newValue)})];
            bar(app.UIAxes, categories, values)
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Year = app.Button.Text; % initialise year to the first entry
            app.MonthDropDown.Enable = 'off';
            app.DataDropDown.Enable = 'off';
        end

        % Value changed function: BanksListBox
        function BanksListBoxValueChanged(app, event)
            app.SelectedBanks = app.BanksListBox.Value;
            if isempty(app.SelectedBanks)
                app.BanksListBox.Value = app.BanksListBox.Items(1);
                app.SelectedBanks = app.BanksListBox.Value;
            end
        end

        % Selection changed function: YearButtonGroup
        function YearButtonGroupSelectionChanged(app, event)
            selectedButton = app.YearButtonGroup.SelectedObject;
            app.Year = selectedButton.Text;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.SelectedBanks)
                app.BanksListBox.Value = app.BanksListBox.Items(1);
                app.SelectedBanks = app.BanksListBox.Value;
            end
            
            if strcmpi(app.Year,'2019')
                % 2019 data is incomplete so end at August
                app.Months = 1:1:8;
                app.MonthDropDown.Items = { 'January';
                    'February';
                    'March';
                    'April';
                    'May';
                    'June';
                    'July';
                    'August'
                    };
            else
                app.Months = 1:1:12;
                app.MonthDropDown.Items = { 'January';
                    'February';
                    'March';
                    'April';
                    'May';
                    'June';
                    'July';
                    'August';
                    'September';
                    'October';
                    'November';
                    'December'
                    };
            end
            
            app.Data = bank_analysis(app.Year, app.Months, app.SelectedBanks);
            
            % initialise table
            app.MonthDropDown.Value = app.MonthDropDown.Items{1};
            app.UITable.ColumnName = fieldnames(app.Data.(app.MonthDropDown.Items{1}));
            app.UITable.Data = struct2table(app.Data.(app.MonthDropDown.Items{1}));
            
            % re-initialise table and graph drop down
            app.MonthDropDown.Enable = 'on';
            app.DataDropDown.Enable = 'on';
            
            % initialise graph
            fields = fieldnames(app.Data.(app.MonthDropDown.Items{1}));
            app.DataDropDown.Items = fields(2:end);
            app.DataDropDown.Value = app.DataDropDown.Items{1};
            categories = categorical({app.Data.(app.MonthDropDown.Items{1}).Name});
            values = [app.Data.(app.MonthDropDown.Items{1}).(app.DataDropDown.Items{1})];
            bar(app.UIAxes, categories, values)
        end

        % Value changed function: MonthDropDown
        function MonthDropDownValueChanged(app, event)
            value = app.MonthDropDown.Value;
            updatetable(app, value)
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            newValue = app.DataDropDown.Value;
            updateplot(app, newValue)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BanksListBoxLabel = uilabel(app.UIFigure);
            app.BanksListBoxLabel.Text = 'Banks';
            app.BanksListBox = uilistbox(app.UIFigure);
            app.BanksListBox.Items = {'Capitec', 'Investec', 'Nedbank', 'FNB', 'Standard Bank', 'ABSA'};
            app.BanksListBox.Multiselect = 'on';
            app.BanksListBox.ValueChangedFcn = createCallbackFcn(app, @BanksListBoxValueChanged, true);
            app.BanksListBox.Value = {};
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.Value = {'Select multiple banks by clicking on the desired bank(s) while holding CTRL or click + SHIFT for bulk selection. The selected banks appear below the bank selection box.'; ''; 'Click ''Run'' to retrieve data and display on the graph window :-)'};
            app.YearButtonGroup = uibuttongroup(app.UIFigure);
            app.YearButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YearButtonGroupSelectionChanged, true);
            app.YearButtonGroup.Title = 'Year';
            app.Button = uiradiobutton(app.YearButtonGroup);
            app.Button.Text = '2019';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.YearButtonGroup);
            app.Button_2.Text = '2018';
            app.Button_3 = uiradiobutton(app.YearButtonGroup);
            app.Button_3.Text = '2017';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'BA900 DATA FOR SOUTH AFRICAN BANKS';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.MonthDropDownLabel = uilabel(app.UIFigure);
            app.MonthDropDownLabel.Text = 'Month';
            app.MonthDropDown = uidropdown(app.UIFigure);
            app.MonthDropDown.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.MonthDropDown.ValueChangedFcn = createCallbackFcn(app, @MonthDropDownValueChanged, true);
            app.MonthDropDown.Value = 'January';
            app.UIAxes = uiaxes(app.UIFigure);
            app.DataDropDownLabel = uilabel(app.UIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.UIFigure);
            app.DataDropDown.Items = {};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = BA900DialogueBox

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end