classdef BA900Graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        OptionsButton             matlab.ui.control.Button
        MonthDropDown             matlab.ui.control.DropDown
        DataDropdown              matlab.ui.control.DropDown
        SelectdatatodisplayLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogueBox % dialogue box application
        Month
    end
    
    methods (Access = public)
        function updateplot(app, newValue)
            categories = categorical(app.DialogueBox.SelectedBanks);
            
            %fields = fieldnames(app.Data.(app.Month));
            switch newValue
                case 'Deposits'
                    app.UIAxes.Title.String = app.DataDropdown.Items(1);
                    app.UIAxes.YLabel.String = 'Rands (ZAR)';
                    values = [app.Data.(app.Month).Deposits];
                    bar(app.UIAxes, categories, values);
                case app.DataDropdown.Items(2)
                case app.DataDropdown.Items(3)
                case app.DataDropdown.Items(4)
                case app.DataDropdown.Items(5)
                case app.DataDropdown.Items(6)
            end
            
            
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Month = app.MonthDropDown.Items(1);
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            app.OptionsButton.Enable = 'off';
            app.DialogueBox = BA900DialogueBox(app);
        end

        % Value changed function: MonthDropDown
        function MonthDropDownValueChanged(app, event)
            app.Month = app.MonthDropDown.Value;
            updateplot(app, 'Deposits')
        end

        % Value changed function: DataDropdown
        function DataDropdownValueChanged(app, event)
            newValue = app.DataDropdown.Value;
            updateplot(app, newValue)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.OptionsButton = uibutton(app.UIFigure, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Options';
            app.MonthDropDown = uidropdown(app.UIFigure);
            app.MonthDropDown.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.MonthDropDown.ValueChangedFcn = createCallbackFcn(app, @MonthDropDownValueChanged, true);
            app.MonthDropDown.Value = 'January';
            app.DataDropdown = uidropdown(app.UIFigure);
            app.DataDropdown.Items = {'Deposits', 'Loans', 'Loans-to-Deposits', 'Market Share', 'Debt-to-Assets', 'Debt-to-Equity'};
            app.DataDropdown.ValueChangedFcn = createCallbackFcn(app, @DataDropdownValueChanged, true);
            app.DataDropdown.Value = 'Deposits';
            app.SelectdatatodisplayLabel = uilabel(app.UIFigure);
            app.SelectdatatodisplayLabel.Text = 'Select data to display';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BA900Graph

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end